classdef WEB_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CatlogodePlantasMenu        matlab.ui.container.Menu
        ConfiguracindeSensoresMenu  matlab.ui.container.Menu
        MonitoreoMenu               matlab.ui.container.Menu
        AgregarButton               matlab.ui.control.Button
        EliminarButton              matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CalibrarButton              matlab.ui.control.Button
        DetenerButton               matlab.ui.control.Button
        SensorNoCalibradoLampLabel  matlab.ui.control.Label
        SensorNoCalibradoLamp       matlab.ui.control.Lamp
        PresininicialmbarEditFieldLabel  matlab.ui.control.Label
        PresininicialmbarEditField  matlab.ui.control.NumericEditField
        MrgendeErrorSliderLabel     matlab.ui.control.Label
        MrgendeErrorSlider          matlab.ui.control.Slider
        IniciarMonitoreoButton      matlab.ui.control.Button
        DetenerMonitoreoButton      matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = public)
         socket
         t
         AgregarApp              % Dialog box app
         idPlanta
         Nombre
         Sensor
         Presion
         Habilitado
         Descripcion
         
         led
         idSelected
         selected
         
    end
    
    methods (Access = public)
        
        function sendData(app,data)     %Función para mandar datos por el socket
            fwrite(app.socket, data)        %Escribir datos de la variable data en el socket
        end
        
        function data = receiveData(app)    %Función para recibir datos por el socket
            data = fgetl(app.socket);           %Guarda los datos resibidos en la variable data
        end
        
        function updateTable(app)                                                   %Función para actualizar la tabla de los datos
            app.sendData('getPlants')                                                   %Se manda getPlants por la función sendData
            tabla = app.receiveData();                                                  %Se reciben los datos en la variable tabla
            tabla = replace(tabla,"u'","");                                             %Se modifica la tabla para fácil acceso a los datos
            tabla = replace(tabla,"'","");                                              %
            tabla = replace(tabla,"None"," ");                                          %
            tabla = replace(tabla,"[(","");                                             %
            tabla = replace(tabla,")]","");                                             %
            tabla = split(tabla,'), (');                                                %
            tabla = split(tabla,',');                                                   %
            tabla = replace(tabla,"'","");                                              %
            app.idPlanta = tabla(:,1);                                                  %Se guada en la variable idPlanta las ids de toda la tabla
            app.idPlanta = cellfun(@str2num,app.idPlanta);                              %Se convierten los datos a números
            app.Nombre = tabla(:,2);                                                    %Se guada en la variable Nombre los nombres de toda la tabla
            app.Sensor = tabla(:,3);                                                    %Se guada en la variable Sensor el número de sensor de toda la tabla
            app.Sensor = categorical(app.Sensor,{'1';'2'},"Protected",true);            %Se convierten los datos a categorical
            app.Presion = tabla(:,4);                                                   %Se guada en la variable Presión las presiones de toda la tabla
            app.Presion = cellfun(@str2num,app.Presion);                                %Se convierten los datos a números
            app.Habilitado = tabla(:,5);                                                %Se guada en la variable Habilitado las plantas habilitadas de toda la tabla
            app.Habilitado = cellfun(@str2num,app.Habilitado);                          %Se convierten los datos a números
            app.Habilitado = logical(app.Habilitado);                                   %Se convierten los números a logical
            app.Descripcion = tabla(:,6);                                               %Se guada en la variable Descripción las descripciones de toda la tabla
            tabla = table(app.Nombre,app.Sensor,app.Descripcion,app.Habilitado);        %Se crea la variable tabla
            app.UITable.Data = tabla;                                                   %Se crea la tabla a mostrar
        end
        
        function modifyPlant(app,indice,idPlanta,newData)                               %Función para modificar una plant de la tabla
            if indice == 1                                                                  %Si el indice es 1, se modifica el nombre de la planta
                newData = char(newData);                                                    %El dato a modificar se convierte a string
                data = strcat('modifyPlant',',',idPlanta,',','Nombre',',',newData);         %Se concatena la cadena que se va a mandar por el socket
                data = char(data);                                                          %
                app.sendData(data)                                                          %Se manda la variable concatenada al socket
            elseif indice == 2                                                              %Si el indice es 2, se modifica el sensor de la planta
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Sensor',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 3                                                              %Si el indice es 3, se modifica la descripción de la planta
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Descripcion',',',newData);
                data = char(data);
                app.sendData(data)
            else                                                                            %Si el indice es 4, se modifica el habilitador de la planta
                newData = num2str(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Habilitado',',',newData);
                data = char(data);
                app.sendData(data)
            end
            
        end
        
        function confirmClose(app, src, event)              
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                id = num2str(app.idPlanta(app.selected));
                app.deletePlant(id)
                app.receiveData()
                app.updateTable()
                app.EliminarButton.Enable = 'off';
            end
            app.EliminarButton.Enable = 'off';
        end
        
        function deletePlant(app,idPlanta)              %Función para eliminar planta
             data = strcat('deletePlant',',',idPlanta);
             data = char(data);
             app.sendData(data)
        end
        
        function updateEnabledPlants(app)       %Función para actualizar las plantas habilitadas
            app.sendData('getEnabledPlants')
            tabla = app.receiveData();
            tabla = replace(tabla,"u'","");
            tabla = replace(tabla,"'","");
            tabla = replace(tabla,"None"," ");
            tabla = replace(tabla,"[(","");
            tabla = replace(tabla,")]","");
            tabla = split(tabla,'), (');
            tabla = split(tabla,',');
            tabla = replace(tabla,"'","");
            [m,n] = size(tabla);
            if n == 1
                tabla = tabla';
            end
            app.idPlanta = tabla(:,1);
            app.idPlanta = cellfun(@str2num,app.idPlanta);
            app.Nombre = tabla(:,2);
            app.Sensor = tabla(:,3);
            app.Sensor = categorical(app.Sensor,{'1';'2'},"Protected",true);
            app.Presion = tabla(:,4);
            app.Presion = cellfun(@str2num,app.Presion);
            tabla = table(app.Nombre,app.Sensor,app.Presion);
            app.UITable.Data = tabla;
        end
        
        function out = selectedPlants(app)  %Función para seleccionar la planta de la tabla
            count1 = 0;
            count2 = 0;            
            [m,n] = size(app.Sensor);
            if m > 2
                out = 0;
            else
                for i = 1 : m
                    if app.Sensor(i,1) == categorical(1)
                        count1 = count1 + 1;
                    elseif app.Sensor(i,1) == categorical(2)
                        count2 = count2 + 1;
                    end
                end
                if count1 > 1 || count2 > 1
                    out = 0;
                else
                    out = 1;
                end
            end
        end
        
        function startCalibration(app,idPlanta)     %Función para empezar la calibración de la planta selecccionada
            data = strcat('startCalibration',',',idPlanta,',','Calibrando',',','1');
            data = char(data);
            app.sendData(data)
            app.receiveData()
        end
        
        function stopCalibration(app,idPlanta)      %Función para detenr la calibración de la planta seleccionada
            data = strcat('stopCalibration',',',idPlanta,',','Calibrando',',','0');
            data = char(data);
            app.sendData(data)
            app.receiveData()
        end
        
        function getCalibrado(app,idPlanta)     %Función para obtener las plantas que se están calibrando
            data = strcat('getCalibrado',',',num2str(idPlanta));
            data = char(data);
            app.sendData(data);
            tabla = app.receiveData();
            tabla = replace(tabla,",","");
            tabla = replace(tabla,"[(","");
            tabla = replace(tabla,")]","");
            app.led = str2num(tabla);
        end
        
        function callEnabledPlants(app,~,thisEvent)
            app.updateEnabledPlants();
            app.getCalibrado(app.idPlanta(app.idSelected))
            app.SensorNoCalibradoLamp.Visible = 'on';
            app.SensorNoCalibradoLamp.Enable = 'on';
            app.SensorNoCalibradoLampLabel.Visible = 'on';
            app.SensorNoCalibradoLampLabel.Enable = 'on';
            if app.led == 1
                app.SensorNoCalibradoLampLabel.Text = 'Sensor Calibrado';
                app.SensorNoCalibradoLamp.Color = 'green';
            else
                app.SensorNoCalibradoLampLabel.Text = 'Sensor No Calibrado';
                app.SensorNoCalibradoLamp.Color = 'red';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all
            
            app.t =timer;
            app.t.TimerFcn = @(~, thisEvent)app.callEnabledPlants;
            app.t.Period = 1;
            app.t.ExecutionMode = 'fixedSpacing';
            
            app.EliminarButton.Enable = 'off';
            app.socket = tcpip('192.168.0.50', 30000, 'NetworkRole', 'client');
            fopen(app.socket)
            
            app.updateTable()
            
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            app.CatlogodePlantasMenu.Enable = 'off';
            app.AgregarButton.Enable = 'off';
            app.EliminarButton.Enable = 'off';
            app.UITable.Enable = 'off';
            app.ConfiguracindeSensoresMenu.Enable = 'off';
            app.MonitoreoMenu.Enable = 'off';
            
            app.AgregarApp = agregar(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.socket)
            delete(app.t)
            delete(app)
            
        end

        % Callback function
        function ModificarButtonPushed(app, event)
                 
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            id = num2str(app.idPlanta(indices(1)));
            app.modifyPlant(indices(2),id,newData)
            app.receiveData()
            app.updateTable()
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.EliminarButton.Enable = 'on';
            app.CalibrarButton.Enable = 'on';
            indices = event.Indices;
            app.selected = indices(1);
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            plant = app.Nombre(app.selected);
            msg = strcat('¿Estas seguro de eliminar ',plant,'?');
            uiconfirm(app.UIFigure,msg,'Eliminar Planta','CloseFcn',@app.confirmClose);
            
        end

        % Menu selected function: ConfiguracindeSensoresMenu
        function ConfiguracindeSensoresMenuSelected(app, event)
            app.AgregarButton.Visible = 'off';
            app.EliminarButton.Visible = 'off';
            app.CalibrarButton.Visible = 'on';
            app.CalibrarButton.Enable = 'off';
            app.Panel.Visible = 'off';
            app.UITable.Visible = 'on';
            app.SensorNoCalibradoLamp.Visible = 'on';
            app.SensorNoCalibradoLamp.Enable = 'off';
            app.PresininicialmbarEditField.Visible = 'on';
            app.PresininicialmbarEditFieldLabel.Visible = 'on';
            app.MrgendeErrorSlider.Visible = 'on';
            app.MrgendeErrorSliderLabel.Visible = 'on';
            app.IniciarMonitoreoButton.Visible = 'off';
            app.UITable.ColumnEditable = false;
            app.UITable.ColumnName = {'Nombre','Sensor','Presión'};
            app.updateEnabledPlants()
            
            
            data = strcat('getSetting',',','presionInicial');
            data = char(data);
            app.sendData(data)
            value = app.receiveData();
            app.PresininicialmbarEditField.Value = str2num(value);
            
            data = strcat('getSetting',',','Margen');
            data = char(data);
            app.sendData(data)
            value = app.receiveData();
            app.MrgendeErrorSlider.Value = str2num(value);
            
            value = app.MrgendeErrorSlider.Value;
            value = num2str(value);
            msg =strcat('Márgen de Error (',value,'%)');
            app.MrgendeErrorSliderLabel.Text = msg; 
        end

        % Menu selected function: CatlogodePlantasMenu
        function CatlogodePlantasMenuSelected(app, event)
            app.AgregarButton.Visible = 'on';
            app.EliminarButton.Visible = 'on';
            app.EliminarButton.Enable = 'off';
            app.CalibrarButton.Visible = 'off';
            app.Panel.Visible = 'off';
            app.UITable.Visible = 'on';
            app.SensorNoCalibradoLamp.Visible = 'off';
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.MrgendeErrorSlider.Visible = 'off';
            app.MrgendeErrorSliderLabel.Visible = 'off';
            app.IniciarMonitoreoButton.Visible = 'off';
            app.UITable.ColumnEditable = true;
            app.UITable.ColumnName = {'Nombre','Sensor','Descripción','Habilitar'};
            app.updateTable()
            
        end

        % Button pushed function: CalibrarButton
        function CalibrarButtonPushed(app, event)
            app.CatlogodePlantasMenu.Enable = 'off';
            app.MonitoreoMenu.Enable = 'off';
            if app.selectedPlants() == 0
                uialert(app.UIFigure,'¡Habilitar una planta por cada sensor!','Precaución','Icon','warning',"CloseFcn",'uiresume(gcbf)') 
                uiwait(gcbf)
                app.CatlogodePlantasMenu.Enable = 'on';
                app.MonitoreoMenu.Enable = 'on';
            else
                app.idSelected = app.selected;
                start(app.t) 
                app.CalibrarButton.Visible = 'off';
                app.DetenerButton.Visible = 'on';
                app.startCalibration(num2str(app.idPlanta(app.idSelected)))
            end
            
        end

        % Value changed function: PresininicialmbarEditField
        function PresininicialmbarEditFieldValueChanged(app, event)
            value = app.PresininicialmbarEditField.Value;
            value = num2str(value);
            data = strcat('setSetting',',','presionInicial',',',value);
            data = char(data);
            app.sendData(data)
            app.receiveData()
        end

        % Value changed function: MrgendeErrorSlider
        function MrgendeErrorSliderValueChanged(app, event)
            value = app.MrgendeErrorSlider.Value;
            value = num2str(value);
            msg =strcat('Márgen de Error (',value,'%)');
            app.MrgendeErrorSliderLabel.Text = msg;
            data = strcat('setSetting',',','Margen',',',value);
            data = char(data);
            app.sendData(data)
            app.receiveData()
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.CatlogodePlantasMenu.Enable = 'on';
            app.MonitoreoMenu.Enable = 'on';
            stop(app.t)
            app.CalibrarButton.Visible = 'on';
            app.CalibrarButton.Enable = 'off';
            app.DetenerButton.Visible = 'off';
            app.SensorNoCalibradoLamp.Enable = 'off';
            app.SensorNoCalibradoLampLabel.Visible = 'off';
            app.SensorNoCalibradoLamp.Color = [0.80 0.80 0.80];
            app.stopCalibration(num2str(app.idPlanta(app.idSelected)))
        end

        % Menu selected function: MonitoreoMenu
        function MonitoreoMenuSelected(app, event)
            app.AgregarButton.Visible = 'off';
            app.EliminarButton.Visible = 'off';
            app.Panel.Visible = 'on';
            app.UITable.Visible = 'off';
            app.CalibrarButton.Visible = 'off';
            app.DetenerButton.Visible = 'off';
            app.SensorNoCalibradoLamp.Visible = 'off';
            app.SensorNoCalibradoLampLabel.Visible = 'off';
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.MrgendeErrorSlider.Visible = 'off';
            app.MrgendeErrorSliderLabel.Visible = 'off';
            app.IniciarMonitoreoButton.Visible = 'on';
        end

        % Value changing function: MrgendeErrorSlider
        function MrgendeErrorSliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = num2str(changingValue);
            msg =strcat('Márgen de Error (',changingValue,'%)');
            app.MrgendeErrorSliderLabel.Text = msg;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CatlogodePlantasMenu = uimenu(app.UIFigure);
            app.CatlogodePlantasMenu.MenuSelectedFcn = createCallbackFcn(app, @CatlogodePlantasMenuSelected, true);
            app.CatlogodePlantasMenu.Text = 'Catálogo de Plantas';
            app.ConfiguracindeSensoresMenu = uimenu(app.UIFigure);
            app.ConfiguracindeSensoresMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguracindeSensoresMenuSelected, true);
            app.ConfiguracindeSensoresMenu.Text = 'Configuración de Sensores';
            app.MonitoreoMenu = uimenu(app.UIFigure);
            app.MonitoreoMenu.MenuSelectedFcn = createCallbackFcn(app, @MonitoreoMenuSelected, true);
            app.MonitoreoMenu.Text = 'Monitoreo';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'; 'Habilitar'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CalibrarButton = uibutton(app.UIFigure, 'push');
            app.CalibrarButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.CalibrarButton.Visible = 'off';
            app.CalibrarButton.Text = 'Calibrar';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Visible = 'off';
            app.DetenerButton.Text = 'Detener';
            app.SensorNoCalibradoLampLabel = uilabel(app.UIFigure);
            app.SensorNoCalibradoLampLabel.Visible = 'off';
            app.SensorNoCalibradoLampLabel.Text = 'Sensor No Calibrado';
            app.SensorNoCalibradoLamp = uilamp(app.UIFigure);
            app.SensorNoCalibradoLamp.Visible = 'off';
            app.PresininicialmbarEditFieldLabel = uilabel(app.UIFigure);
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Text = 'Presión inicial (mbar)';
            app.PresininicialmbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.PresininicialmbarEditField.Limits = [1000 10000];
            app.PresininicialmbarEditField.ValueChangedFcn = createCallbackFcn(app, @PresininicialmbarEditFieldValueChanged, true);
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditField.Value = 1000;
            app.MrgendeErrorSliderLabel = uilabel(app.UIFigure);
            app.MrgendeErrorSliderLabel.Visible = 'off';
            app.MrgendeErrorSliderLabel.Text = 'Márgen de  Error (%)';
            app.MrgendeErrorSlider = uislider(app.UIFigure);
            app.MrgendeErrorSlider.Limits = [5 50];
            app.MrgendeErrorSlider.MajorTicks = [5 10 15 20 25 30 35 40 45 50];
            app.MrgendeErrorSlider.MajorTickLabels = {'5', '10', '15', '20', '25', '30', '35', '40', '45', '50'};
            app.MrgendeErrorSlider.ValueChangedFcn = createCallbackFcn(app, @MrgendeErrorSliderValueChanged, true);
            app.MrgendeErrorSlider.ValueChangingFcn = createCallbackFcn(app, @MrgendeErrorSliderValueChanging, true);
            app.MrgendeErrorSlider.MinorTicks = [5 10 15 20 25 30 35 40 45 50];
            app.MrgendeErrorSlider.Visible = 'off';
            app.MrgendeErrorSlider.Value = 10;
            app.IniciarMonitoreoButton = uibutton(app.UIFigure, 'push');
            app.IniciarMonitoreoButton.Visible = 'off';
            app.IniciarMonitoreoButton.Text = 'Iniciar Monitoreo';
            app.DetenerMonitoreoButton = uibutton(app.UIFigure, 'push');
            app.DetenerMonitoreoButton.Visible = 'off';
            app.DetenerMonitoreoButton.Text = 'Detener Monitoreo';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WEB_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end