classdef agregar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AgregarPlanta            matlab.ui.Figure
        AgregarPlantaLabel       matlab.ui.control.Label
        NombreEditFieldLabel     matlab.ui.control.Label
        NombrePlanta             matlab.ui.control.EditField
        SensorLabel              matlab.ui.control.Label
        SensorPlanta             matlab.ui.control.DropDown
        AgregarButton            matlab.ui.control.Button
        CancelarButton           matlab.ui.control.Button
        DescripcinTextAreaLabel  matlab.ui.control.Label
        DescripcionTextArea      matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        CallingApp   % Main app object
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            %app.CallingApp.Visible = 'off';
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.AgregarButton.Enable = 'on';
            app.CallingApp.EliminarButton.Enable = 'off';
            app.CallingApp.CatlogodePlantasMenu.Enable = 'on';
            app.CallingApp.UITable.Enable = 'on';
            app.CallingApp.ConfiguracindeSensoresMenu.Enable = 'on';
            app.CallingApp.MonitoreoMenu.Enable = 'on';
            %app.CallingApp.Visible = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            %Enable the Plot Opions button in main app

            if (isempty(app.NombrePlanta.Value) == 1)
                uialert(app.AgregarPlanta,'Nombre invalido','Error',"CloseFcn",'uiresume(gcbf)');
                uiwait(gcbf)
            else
                if (isempty(app.DescripcionTextArea.Value) == 1)
                    app.DescripcionTextArea.Value = '';
                end
                    
                data = strcat('newPlant',',',app.NombrePlanta.Value,',',app.SensorPlanta.Value,',','0',',','1',',',app.DescripcionTextArea.Value,',','0',',','0');
                data = char(data);
                app.CallingApp.sendData(data)
                app.CallingApp.receiveData()
                
                uialert(app.AgregarPlanta,'Agregado','Operación Exitosa','Icon','success',"CloseFcn",'uiresume(gcbf)') 
                uiwait(gcbf)
                %uiwait(uialert(app.AgregarPlanta,'Operación Exitosa','Agregado','Icon','success'))
                %uiwait(msgbox('Operation Completed','Success'))
                %app.CallingApp.Visible = 'on';
                % Delete the dialog box 
                app.CallingApp.updateTable()
                app.CallingApp.AgregarButton.Enable = 'on';
                app.CallingApp.EliminarButton.Enable = 'off';
                app.CallingApp.CatlogodePlantasMenu.Enable = 'on';
                app.CallingApp.UITable.Enable = 'on';
                app.CallingApp.ConfiguracindeSensoresMenu.Enable = 'on';
                app.CallingApp.MonitoreoMenu.Enable = 'on';
                delete(app)
                
            end
            
        end

        % Close request function: AgregarPlanta
        function AgregarPlantaCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.CatlogodePlantasMenu.Enable = 'on';
            app.CallingApp.AgregarButton.Enable = 'on';
            app.CallingApp.EliminarButton.Enable = 'off';
            app.CallingApp.UITable.Enable = 'on';
            app.CallingApp.ConfiguracindeSensoresMenu.Enable = 'on';
            app.CallingApp.MonitoreoMenu.Enable = 'on';
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AgregarPlanta = uifigure('Visible', 'off');
            app.AgregarPlanta.Name = 'AgregarPlanta';
            app.AgregarPlanta.CloseRequestFcn = createCallbackFcn(app, @AgregarPlantaCloseRequest, true);
            app.AgregarPlantaLabel = uilabel(app.AgregarPlanta);
            app.AgregarPlantaLabel.Text = 'Agregar Planta';
            app.NombreEditFieldLabel = uilabel(app.AgregarPlanta);
            app.NombreEditFieldLabel.Text = 'Nombre:';
            app.NombrePlanta = uieditfield(app.AgregarPlanta, 'text');
            app.SensorLabel = uilabel(app.AgregarPlanta);
            app.SensorLabel.Text = 'Sensor:';
            app.SensorPlanta = uidropdown(app.AgregarPlanta);
            app.SensorPlanta.Items = {'1', '2'};
            app.SensorPlanta.Value = '1';
            app.AgregarButton = uibutton(app.AgregarPlanta, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.CancelarButton = uibutton(app.AgregarPlanta, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
            app.DescripcinTextAreaLabel = uilabel(app.AgregarPlanta);
            app.DescripcinTextAreaLabel.Text = 'Descripción:';
            app.DescripcionTextArea = uitextarea(app.AgregarPlanta);
            % Show the figure after all components are created
            app.AgregarPlanta.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agregar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AgregarPlanta)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AgregarPlanta)
        end
    end
end