classdef calibrar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrarApp                 matlab.ui.Figure
        PresinGaugeLabel            matlab.ui.control.Label
        PresinGauge                 matlab.ui.control.Gauge
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        MrgendeError10SliderLabel   matlab.ui.control.Label
        MrgendeError10Slider        matlab.ui.control.Slider
        PresinInicialmbarEditFieldLabel  matlab.ui.control.Label
        PresinInicialmbarEditField  matlab.ui.control.NumericEditField
        mbarLabel                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        IniciarButton               matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        CallingApp % Description
        id
        nombre
        indice
        sensor
        t
    end
    
    methods (Access = public)
        
        function changeGauge(app)
            presion = str2num(app.CallingApp.getSetting('getSetting#presionInicial'));
            margen = str2num(app.CallingApp.getSetting('getSetting#Margen'));
            presion = str2num(presion);
            margen = str2num(margen);
            
            max = 2 * presion;
            inferior = presion - ((margen/100) * presion);
            superior = presion + ((margen/100) * presion);
            app.PresinGauge.Limits = [0  max];
            app.PresinGauge.ScaleColorLimits = [inferior superior; superior max;0 inferior];
        end
        
        function verificarCalibrados(app)
            tabla = app.CallingApp.getCalibratedPlants();
            [numRows,numCols] = size(tabla);
            if numRows == 0
                app.CallingApp.setCalibrando('1',app.id)
                app.IniciarButton.Text = "Detener";
            elseif numRows == 1
                tabla = cellfun(@transpose,tabla,'UniformOutput',false);
                tabla=cat(1,tabla{:});
                if cell2mat(tabla(numRows)) == app.id
                    dato = strcat("¿Recalibrar ",app.nombre,'?');
                    selection = uiconfirm(app.CalibrarApp,dato,'Precaución','Icon','warning');
                    selection = string(selection);
                    
                    if selection == "OK"
                        app.CallingApp.setCalibrado('0',app.id)
                        app.CallingApp.setCalibrando('1',app.id)
                        app.IniciarButton.Text = "Detener";
                    else
                        app.IniciarButton.Value = 0;
                    end
                elseif cell2mat(tabla(2)) == app.sensor
                    dato = strcat("Sólo se puede calibrar una planta por sensor, ¿Olvidar la calibracio de ",string(tabla(3)),'?');
                    selection = uiconfirm(app.CalibrarApp,dato,'Precaución','Icon','warning');
                    selection = string(selection);
                    
                    if selection == "OK"
                        app.CallingApp.setCalibrado('0',cell2mat(tabla(1)))
                        app.CallingApp.setCalibrando('1',app.id)
                        app.IniciarButton.Text = "Detener";
                    else
                        app.IniciarButton.Value = 0;
                    end
                    
                else
                    app.CallingApp.setCalibrando('1',app.id)
                    app.IniciarButton.Text = "Detener";
                end
            else
                tabla = cellfun(@transpose,tabla,'UniformOutput',false);
                tabla=cat(1,tabla{:});
                if cell2mat(tabla(1,2)) == app.sensor
                    dato = strcat("Sólo se puede calibrar una planta por sensor, ¿Olvidar la calibracio de ",string(tabla(1,3)),'?');
                    selection = uiconfirm(app.CalibrarApp,dato,'Precaución','Icon','warning');
                    selection = string(selection);
                    
                    if selection == "OK"
                        app.CallingApp.setCalibrado('0',cell2mat(tabla(1,1)))
                        app.CallingApp.setCalibrando('1',app.id)
                        app.IniciarButton.Text = "Detener";
                    else
                        app.IniciarButton.Value = 0;
                    end
                else
                    dato = strcat("Sólo se puede calibrar una planta por sensor, ¿Olvidar la calibracio de ",string(tabla(2,3)),'?');
                    selection = uiconfirm(app.CalibrarApp,dato,'Precaución','Icon','warning');
                    selection = string(selection);
                    
                    if selection == "OK"
                        app.CallingApp.setCalibrado('0',cell2mat(tabla(2,1)))
                        app.CallingApp.setCalibrando('1',app.id)
                        app.IniciarButton.Text = "Detener";
                    else
                        app.IniciarButton.Value = 0;
                    end
                end
             
            end
            
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.CallingApp.UIFigure.Visible = 'off';
            app.indice = app.CallingApp.indicesConfigTable(1);
            app.id =  table2array(app.CallingApp.Id);
            app.id = app.id(app.indice);
            app.nombre = string(table2array(app.CallingApp.Nombre));
            app.nombre = app.nombre(app.indice);
            app.sensor = table2array(app.CallingApp.sensor);
            app.sensor = app.sensor(app.indice);
            app.sensor = double(app.sensor);
            
                    
            app.Label.Text = app.nombre;
            
            app.t =timer;
            app.t.TimerFcn = @(~, thisEvent)app.CallingApp.updatePressureCalibrate();
            app.t.Period = 0.1;
            app.t.ExecutionMode = 'fixedSpacing';
        end

        % Close request function: CalibrarApp
        function CalibrarAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.UIFigure.Visible = 'on';
            app.CallingApp.modifySetting('setSetting#presionInicial#1000');
            app.CallingApp.modifySetting('setSetting#Margen#10');
            app.CallingApp.CalibrarButton.Enable  = 'off';
            
            %app.CalibrarApp.updateConfigurationTable()
            delete(app.t) 
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: PresinInicialmbarEditField
        function PresinInicialmbarEditFieldValueChanged(app, event)
            value = app.PresinInicialmbarEditField.Value;
            data = strcat('setSetting','#','presionInicial','#',num2str(value));
            app.CallingApp.modifySetting(data)
            app.changeGauge()
            
        end

        % Value changed function: MrgendeError10Slider
        function MrgendeError10SliderValueChanged(app, event)
            value = app.MrgendeError10Slider.Value;
            data = strcat('setSetting','#','Margen','#',num2str(value));
            app.CallingApp.modifySetting(data)
            app.changeGauge()
        end

        % Value changing function: MrgendeError10Slider
        function MrgendeError10SliderValueChanging(app, event)
            changingValue = event.Value;
            changingValue = num2str(changingValue);
            msg =strcat('Márgen de Error (',changingValue,'%)');
            app.MrgendeError10SliderLabel.Text = msg;
        end

        % Value changed function: IniciarButton
        function IniciarButtonValueChanged(app, event)
            value = app.IniciarButton.Value;
            if value == 1
                app.verificarCalibrados()
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrarApp = uifigure('Visible', 'off');
            app.CalibrarApp.Name = 'UI Figure';
            app.CalibrarApp.CloseRequestFcn = createCallbackFcn(app, @CalibrarAppCloseRequest, true);
            app.PresinGaugeLabel = uilabel(app.CalibrarApp);
            app.PresinGaugeLabel.Text = 'Presión';
            app.PresinGauge = uigauge(app.CalibrarApp, 'circular');
            app.PresinGauge.Limits = [0 2000];
            app.StatusLampLabel = uilabel(app.CalibrarApp);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.CalibrarApp);
            app.StatusLamp.Tooltip = {''};
            app.MrgendeError10SliderLabel = uilabel(app.CalibrarApp);
            app.MrgendeError10SliderLabel.Text = 'Márgen de  Error (10%)';
            app.MrgendeError10Slider = uislider(app.CalibrarApp);
            app.MrgendeError10Slider.Limits = [10 90];
            app.MrgendeError10Slider.ValueChangedFcn = createCallbackFcn(app, @MrgendeError10SliderValueChanged, true);
            app.MrgendeError10Slider.ValueChangingFcn = createCallbackFcn(app, @MrgendeError10SliderValueChanging, true);
            app.MrgendeError10Slider.MinorTicks = [10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.MrgendeError10Slider.Value = 10;
            app.PresinInicialmbarEditFieldLabel = uilabel(app.CalibrarApp);
            app.PresinInicialmbarEditFieldLabel.Text = 'Presión Inicial (mbar)';
            app.PresinInicialmbarEditField = uieditfield(app.CalibrarApp, 'numeric');
            app.PresinInicialmbarEditField.ValueChangedFcn = createCallbackFcn(app, @PresinInicialmbarEditFieldValueChanged, true);
            app.PresinInicialmbarEditField.Value = 1000;
            app.mbarLabel = uilabel(app.CalibrarApp);
            app.mbarLabel.Text = '0 mbar';
            app.Label = uilabel(app.CalibrarApp);
            app.Label.Text = '';
            app.IniciarButton = uibutton(app.CalibrarApp, 'state');
            app.IniciarButton.ValueChangedFcn = createCallbackFcn(app, @IniciarButtonValueChanged, true);
            app.IniciarButton.Text = 'Iniciar';
            % Show the figure after all components are created
            app.CalibrarApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrarApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrarApp)
        end
    end
end